classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SRClocationEditField           matlab.ui.control.EditField
        SRClocationEditFieldLabel      matlab.ui.control.Label
        DutySpinner_2                  matlab.ui.control.Spinner
        DutySpinner_2Label             matlab.ui.control.Label
        PhaseSpinner_2                 matlab.ui.control.Spinner
        PhaseSpinner_2Label            matlab.ui.control.Label
        FrequencySpinner_2             matlab.ui.control.Spinner
        FrequencySpinner_2Label        matlab.ui.control.Label
        AmplitudeSpinner_2             matlab.ui.control.Spinner
        AmplitudeSpinner_2Label        matlab.ui.control.Label
        DutySpinner                    matlab.ui.control.Spinner
        DutySpinnerLabel               matlab.ui.control.Label
        DurationSpinner                matlab.ui.control.Spinner
        DurationSpinnerLabel           matlab.ui.control.Label
        PhaseSpinner                   matlab.ui.control.Spinner
        PhaseSpinnerLabel              matlab.ui.control.Label
        SamplingFsSpinner              matlab.ui.control.Spinner
        SamplingFsSpinnerLabel         matlab.ui.control.Label
        FrequencySpinner               matlab.ui.control.Spinner
        FrequencySpinnerLabel          matlab.ui.control.Label
        AmplitudeSpinner               matlab.ui.control.Spinner
        AmplitudeSpinnerLabel          matlab.ui.control.Label
        MiddleSpinner                  matlab.ui.control.Spinner
        MiddleSpinnerLabel             matlab.ui.control.Label
        AdditionalFilterDropDown       matlab.ui.control.DropDown
        AdditionalFilterDropDownLabel  matlab.ui.control.Label
        CeilingSpinner_2               matlab.ui.control.Spinner
        CeilingSpinner_2Label          matlab.ui.control.Label
        FloorSpinner_2                 matlab.ui.control.Spinner
        FloorSpinner_2Label            matlab.ui.control.Label
        Generator2DropDown             matlab.ui.control.DropDown
        Generator2DropDownLabel        matlab.ui.control.Label
        Generator1DropDown             matlab.ui.control.DropDown
        Generator1DropDownLabel        matlab.ui.control.Label
        CeilingSpinner                 matlab.ui.control.Spinner
        CeilingSpinnerLabel            matlab.ui.control.Label
        FloorSpinner                   matlab.ui.control.Spinner
        FloorSpinnerLabel              matlab.ui.control.Label
        BandPassFilterLabel            matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        RecordButton                   matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        AmpEnvelopeDropDown            matlab.ui.control.DropDown
        AmpEnvelopeDropDownLabel       matlab.ui.control.Label
        PitchEnvelopeDropDownLabel     matlab.ui.control.Label
        PitchEnvelopeDropDown          matlab.ui.control.DropDown
        A_RelSlider                    matlab.ui.control.Slider
        RelSlider_3Label               matlab.ui.control.Label
        A_SusSlider                    matlab.ui.control.Slider
        SusSlider_3Label               matlab.ui.control.Label
        A_DecaySlider                  matlab.ui.control.Slider
        DecaySlider_3Label             matlab.ui.control.Label
        A_AttackSlider                 matlab.ui.control.Slider
        AttackSlider_3Label            matlab.ui.control.Label
        P_RelSlider                    matlab.ui.control.Slider
        RelSlider_2Label               matlab.ui.control.Label
        P_SusSlider                    matlab.ui.control.Slider
        SusSlider_2Label               matlab.ui.control.Label
        P_DecaySlider                  matlab.ui.control.Slider
        DecaySlider_2Label             matlab.ui.control.Label
        P_AttackSlider                 matlab.ui.control.Slider
        AttackSlider_2Label            matlab.ui.control.Label
        ModulationLabel                matlab.ui.control.Label
        LFOLabel                       matlab.ui.control.Label
        OscFreqSpinner                 matlab.ui.control.Spinner
        OscFreqSpinnerLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Property % Description
        play = 0;
    end
    
    methods (Access = public)
        function results = func(app)
            %Basic Example test 
            %Written by Darell
            fs = app.SamplingFsSpinner.Value();
            duration = app.DurationSpinner.Value();
            
            tdomain = 0:1/fs:(duration - 1/fs); %to plot against t     
            Len = length(tdomain);
            Fdomain = fs * (-Len/2 : (Len/2 - 1))/Len ; %to plot against F
            FTtestinput = 100 * ones([1,Len]);
            
            %Sound Bite generator 
            amplitude = app.AmplitudeSpinner.Value();
            frequency = app.FrequencySpinner.Value();
            phase = app.PhaseSpinner.Value();
            
            duty = app.DutySpinner.Value();
            number = app.Generator1DropDown.Value();
            x = SoundGeneratorSelect(amplitude, frequency, phase, fs, duration, duty,number);
            
            amplitude = app.AmplitudeSpinner_2.Value();
            frequency = app.FrequencySpinner_2.Value();
            phase = app.PhaseSpinner_2.Value();
            duty = app.DutySpinner_2.Value();
            number = app.Generator2DropDown.Value();
            x = x + SoundGeneratorSelect(amplitude, frequency, phase, fs, duration, duty,number);
            
            
            %Band Pass Filter
            LOW = app.FloorSpinner.Value();
            HIGH = app.CeilingSpinner.Value();
            MED = "unused";

            new_x = DarellbandpassFilter(x,fs,LOW,MED,HIGH);
            fttestinput = ifft(FTtestinput);
            fttestoutput = DarellbandpassFilter(fttestinput,fs,LOW,MED,HIGH);
            
            FilterEnv = fftshift(fft(fttestoutput));
            plot(app.UIAxes_3,Fdomain,FilterEnv)
            
            x = new_x;
            
            %Amplitude Envelope
            attack = app.A_AttackSlider.Value();
            decay = app.A_DecaySlider.Value();
            sustain = app.A_SusSlider.Value();
            release = app.A_RelSlider.Value();
            
            number = app.AmpEnvelopeDropDown.Value();
            new_x = AmpEnvelopeSelect(x, fs, attack,decay,sustain,release,number); %output new sound in time domain
           
            AmplitudeEnv = new_x ./ x;
            plot(app.UIAxes_2,tdomain,AmplitudeEnv)
            plot(app.UIAxes,tdomain,new_x)
            
            after_freq = fftshift(fft(new_x));    
            plot(app.UIAxes2,Fdomain,after_freq)

            app.play_continuous_inapp(new_x, fs)


        end
        
        function play_continuous_inapp(app, wave, fs)
            soundfile = audioplayer(wave,fs);
            while app.play > 0 %change to counts/while play button active
                playblocking(soundfile);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(string(app.SRClocationEditField.Value()));
            app.play = 0;
        end

        % Callback function
        function PitchEnvelopeListBoxValueChanged(app, event)
            value = app.PitchEnvelopeListBox.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {639, 639, 639};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {639, 639};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = 1;
            app.func()
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.play = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BandPassFilterLabel = uilabel(app.LeftPanel);
            app.BandPassFilterLabel.Text = 'Band-Pass Filter';
            app.FloorSpinnerLabel = uilabel(app.LeftPanel);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.LeftPanel);
            app.FloorSpinner.Step = 100;
            app.FloorSpinner.Limits = [0 Inf];
            app.FloorSpinner.Value = 20;
            app.CeilingSpinnerLabel = uilabel(app.LeftPanel);
            app.CeilingSpinnerLabel.Text = 'Ceiling';
            app.CeilingSpinner = uispinner(app.LeftPanel);
            app.CeilingSpinner.Step = 100;
            app.CeilingSpinner.Limits = [0 Inf];
            app.CeilingSpinner.Value = 20000;
            app.Generator1DropDownLabel = uilabel(app.LeftPanel);
            app.Generator1DropDownLabel.Text = 'Generator 1';
            app.Generator1DropDown = uidropdown(app.LeftPanel);
            app.Generator2DropDownLabel = uilabel(app.LeftPanel);
            app.Generator2DropDownLabel.Text = 'Generator 2';
            app.Generator2DropDown = uidropdown(app.LeftPanel);
            app.Generator2DropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'NIL'};
            app.Generator2DropDown.Value = 'NIL';
            app.FloorSpinner_2Label = uilabel(app.LeftPanel);
            app.FloorSpinner_2Label.Text = 'Floor';
            app.FloorSpinner_2 = uispinner(app.LeftPanel);
            app.FloorSpinner_2.Step = 100;
            app.CeilingSpinner_2Label = uilabel(app.LeftPanel);
            app.CeilingSpinner_2Label.Text = 'Ceiling';
            app.CeilingSpinner_2 = uispinner(app.LeftPanel);
            app.CeilingSpinner_2.Step = 100;
            app.CeilingSpinner_2.Value = 22000;
            app.AdditionalFilterDropDownLabel = uilabel(app.LeftPanel);
            app.AdditionalFilterDropDownLabel.Text = 'Additional Filter';
            app.AdditionalFilterDropDown = uidropdown(app.LeftPanel);
            app.AdditionalFilterDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'NIL'};
            app.AdditionalFilterDropDown.Value = 'NIL';
            app.MiddleSpinnerLabel = uilabel(app.LeftPanel);
            app.MiddleSpinnerLabel.Text = 'Middle';
            app.MiddleSpinner = uispinner(app.LeftPanel);
            app.MiddleSpinner.Step = 100;
            app.MiddleSpinner.Value = 11000;
            app.AmplitudeSpinnerLabel = uilabel(app.LeftPanel);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.LeftPanel);
            app.AmplitudeSpinner.Step = 100;
            app.AmplitudeSpinner.Limits = [0 Inf];
            app.AmplitudeSpinner.Value = 1;
            app.FrequencySpinnerLabel = uilabel(app.LeftPanel);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.LeftPanel);
            app.FrequencySpinner.Step = 100;
            app.FrequencySpinner.Limits = [0 Inf];
            app.FrequencySpinner.Value = 200;
            app.SamplingFsSpinnerLabel = uilabel(app.LeftPanel);
            app.SamplingFsSpinnerLabel.Text = 'Sampling Fs';
            app.SamplingFsSpinner = uispinner(app.LeftPanel);
            app.SamplingFsSpinner.Step = 100;
            app.SamplingFsSpinner.Limits = [0 Inf];
            app.SamplingFsSpinner.Value = 44800;
            app.PhaseSpinnerLabel = uilabel(app.LeftPanel);
            app.PhaseSpinnerLabel.Text = 'Phase';
            app.PhaseSpinner = uispinner(app.LeftPanel);
            app.PhaseSpinner.Step = 100;
            app.PhaseSpinner.Limits = [0 Inf];
            app.DurationSpinnerLabel = uilabel(app.LeftPanel);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.LeftPanel);
            app.DurationSpinner.Step = 100;
            app.DurationSpinner.Limits = [0 Inf];
            app.DurationSpinner.Value = 1;
            app.DutySpinnerLabel = uilabel(app.LeftPanel);
            app.DutySpinnerLabel.Text = 'Duty';
            app.DutySpinner = uispinner(app.LeftPanel);
            app.DutySpinner.Step = 100;
            app.DutySpinner.Limits = [0 Inf];
            app.DutySpinner.Value = 0.01;
            app.AmplitudeSpinner_2Label = uilabel(app.LeftPanel);
            app.AmplitudeSpinner_2Label.Text = 'Amplitude';
            app.AmplitudeSpinner_2 = uispinner(app.LeftPanel);
            app.AmplitudeSpinner_2.Step = 100;
            app.AmplitudeSpinner_2.Limits = [0 Inf];
            app.AmplitudeSpinner_2.Value = 1;
            app.FrequencySpinner_2Label = uilabel(app.LeftPanel);
            app.FrequencySpinner_2Label.Text = 'Frequency';
            app.FrequencySpinner_2 = uispinner(app.LeftPanel);
            app.FrequencySpinner_2.Step = 100;
            app.FrequencySpinner_2.Limits = [0 Inf];
            app.FrequencySpinner_2.Value = 202;
            app.PhaseSpinner_2Label = uilabel(app.LeftPanel);
            app.PhaseSpinner_2Label.Text = 'Phase';
            app.PhaseSpinner_2 = uispinner(app.LeftPanel);
            app.PhaseSpinner_2.Step = 100;
            app.PhaseSpinner_2.Limits = [0 Inf];
            app.DutySpinner_2Label = uilabel(app.LeftPanel);
            app.DutySpinner_2Label.Text = 'Duty';
            app.DutySpinner_2 = uispinner(app.LeftPanel);
            app.DutySpinner_2.Step = 100;
            app.DutySpinner_2.Limits = [0 Inf];
            app.DutySpinner_2.Value = 0.01;
            app.SRClocationEditFieldLabel = uilabel(app.LeftPanel);
            app.SRClocationEditFieldLabel.Text = 'SRC location:';
            app.SRClocationEditField = uieditfield(app.LeftPanel, 'text');
            app.SRClocationEditField.Value = '/Users/DarellCHUA/Documents/GitHub/ece45-project/src';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Amplitude Envelope')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.CenterPanel);
            title(app.UIAxes_3, 'Filter')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.CenterPanel);
            title(app.UIAxes_4, 'Pitch Envelope')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.PlayButton = uibutton(app.CenterPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.CenterPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RecordButton = uibutton(app.CenterPanel, 'push');
            app.RecordButton.Text = 'Record';
            app.RightPanel = uipanel(app.GridLayout);
            app.OscFreqSpinnerLabel = uilabel(app.RightPanel);
            app.OscFreqSpinnerLabel.Text = 'Osc Freq';
            app.OscFreqSpinner = uispinner(app.RightPanel);
            app.OscFreqSpinner.Limits = [0 20];
            app.LFOLabel = uilabel(app.RightPanel);
            app.LFOLabel.Text = 'LFO';
            app.ModulationLabel = uilabel(app.RightPanel);
            app.ModulationLabel.Text = 'Modulation';
            app.AttackSlider_2Label = uilabel(app.RightPanel);
            app.AttackSlider_2Label.Text = 'Attack';
            app.P_AttackSlider = uislider(app.RightPanel);
            app.P_AttackSlider.Limits = [0 1];
            app.P_AttackSlider.Value = 0.2;
            app.DecaySlider_2Label = uilabel(app.RightPanel);
            app.DecaySlider_2Label.Text = 'Decay';
            app.P_DecaySlider = uislider(app.RightPanel);
            app.P_DecaySlider.Limits = [0 1];
            app.P_DecaySlider.Value = 0.2;
            app.SusSlider_2Label = uilabel(app.RightPanel);
            app.SusSlider_2Label.Text = 'Sus';
            app.P_SusSlider = uislider(app.RightPanel);
            app.P_SusSlider.Limits = [0 1];
            app.P_SusSlider.Value = 0.8;
            app.RelSlider_2Label = uilabel(app.RightPanel);
            app.RelSlider_2Label.Text = 'Rel';
            app.P_RelSlider = uislider(app.RightPanel);
            app.P_RelSlider.Limits = [0 1];
            app.P_RelSlider.Value = 0.2;
            app.AttackSlider_3Label = uilabel(app.RightPanel);
            app.AttackSlider_3Label.Text = 'Attack';
            app.A_AttackSlider = uislider(app.RightPanel);
            app.A_AttackSlider.Limits = [0 1];
            app.A_AttackSlider.Value = 0.2;
            app.DecaySlider_3Label = uilabel(app.RightPanel);
            app.DecaySlider_3Label.Text = 'Decay';
            app.A_DecaySlider = uislider(app.RightPanel);
            app.A_DecaySlider.Limits = [0 1];
            app.A_DecaySlider.Value = 0.2;
            app.SusSlider_3Label = uilabel(app.RightPanel);
            app.SusSlider_3Label.Text = 'Sus';
            app.A_SusSlider = uislider(app.RightPanel);
            app.A_SusSlider.Limits = [0 1];
            app.A_SusSlider.Value = 0.8;
            app.RelSlider_3Label = uilabel(app.RightPanel);
            app.RelSlider_3Label.Text = 'Rel';
            app.A_RelSlider = uislider(app.RightPanel);
            app.A_RelSlider.Limits = [0 1];
            app.A_RelSlider.Value = 0.2;
            app.PitchEnvelopeDropDown = uidropdown(app.RightPanel);
            app.PitchEnvelopeDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'NIL'};
            app.PitchEnvelopeDropDown.Value = 'NIL';
            app.PitchEnvelopeDropDownLabel = uilabel(app.RightPanel);
            app.PitchEnvelopeDropDownLabel.Text = 'Pitch Envelope';
            app.AmpEnvelopeDropDownLabel = uilabel(app.RightPanel);
            app.AmpEnvelopeDropDownLabel.Text = 'Amp Envelope';
            app.AmpEnvelopeDropDown = uidropdown(app.RightPanel);
            app.AmpEnvelopeDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'NIL'};
            app.AmpEnvelopeDropDown.Value = 'NIL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end